classdef Origami_Generator_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        uitoolbar1                      matlab.ui.container.Toolbar
        uipushtool1                     matlab.ui.container.toolbar.PushTool
        uitoggletool1                   matlab.ui.container.toolbar.ToggleTool
        uitoggletool2                   matlab.ui.container.toolbar.ToggleTool
        uitoggletool3                   matlab.ui.container.toolbar.ToggleTool
        uitoggletool4                   matlab.ui.container.toolbar.ToggleTool
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        pushbutton3                     matlab.ui.control.Button
        pushbutton3_2                   matlab.ui.control.Button
        uipanel1                        matlab.ui.container.Panel
        text4                           matlab.ui.control.Label
        text2                           matlab.ui.control.Label
        pushbutton9                     matlab.ui.control.Button
        pushbutton1                     matlab.ui.control.Button
        edit2                           matlab.ui.control.EditField
        edit1                           matlab.ui.control.EditField
        edit3                           matlab.ui.control.EditField
        text3                           matlab.ui.control.Label
        uipanel4                        matlab.ui.container.Panel
        text32_2                        matlab.ui.control.Label
        pushbutton4                     matlab.ui.control.Button
        slider1                         matlab.ui.control.Slider
        edit7                           matlab.ui.control.EditField
        edit12                          matlab.ui.control.EditField
        text29                          matlab.ui.control.Label
        text24                          matlab.ui.control.Label
        edit8                           matlab.ui.control.EditField
        text21                          matlab.ui.control.Label
        text20                          matlab.ui.control.Label
        uipanel2                        matlab.ui.container.Panel
        ClicktoaddlockingpatternButton  matlab.ui.control.StateButton
        pfnameh_4                       matlab.ui.control.Label
        edit4_3                         matlab.ui.control.EditField
        pfnameh_3                       matlab.ui.control.Label
        edit4_2                         matlab.ui.control.EditField
        pfnameh_2                       matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        Panel                           matlab.ui.container.Panel
        pushbutton7                     matlab.ui.control.Button
        pushbutton10                    matlab.ui.control.Button
        LayersSpinner                   matlab.ui.control.Spinner
        LayersSpinnerLabel              matlab.ui.control.Label
        uipanel6                        matlab.ui.container.Panel
        text32                          matlab.ui.control.Label
        slider2                         matlab.ui.control.Slider
        text13_2                        matlab.ui.control.Label
        text13                          matlab.ui.control.Label
        axes1                           matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    methods (Access = private)

        function resetInteractions(app, event)
            % This function resets the states of the toggle tools that
            % impact user interactions.  It also resets the figure interactions.
             
            % Find all tools to reset.  Exclude the tool associated
            % with the event.
            interactiveTools = [app.uitoggletool1, app.uitoggletool2, app.uitoggletool3, app.uitoggletool4];
            interactiveTools(event.Source == interactiveTools) = [];
             
            % Set the state of the tools to 'off'.
            [interactiveTools.State] = deal('off');
             
            % Set figure interactions to 'off'.
            datacursormode(app.figure1, 'off')
            rotate3d(app.figure1, 'off');
            pan(app.figure1, 'off');
            zoom(app.figure1,'off');
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Origami_Generator_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run            
            movegui(app.figure1, 'center');
%             app.figure1.Name = 'Origami Generator 2.0';
%             app.figure1.Icon='logo.png';

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to untitled (see VARARGIN)
            
            % Choose default command line output for untitled
            
            handles.output = hObject;
%             Logo= imread('title.png');
%             warning off;
%             javaImage = im2java(Logo);
%             newIcon = javax.swing.ImageIcon(javaImage);
%             figFrame = get(handles.figure1,'JavaFrame'); %取得Figure的JavaFrame。
%             figFrame.setFigureIcon(newIcon); %修改图标
            % set(fig.ToolBar,'tag','figMenuFile'),'BackGroundColor',[1/255 1/255 1/255]);
            % guidata(hObject, handles);
            
            % Update fig
%             guidata(hObject, handles);
%             axes(handles.axes2);
%             imshow('cityu.jpeg');
%             axes(handles.axes1);
            specification=imread('specification.png');
            imshow(specification,'Parent',handles.axes1);
            %off button x
%             set(handles.pushbutton8,'visible','off');
        end

        % Value changed function: edit12, edit7, edit8
        function edit7_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
%             [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); 
            
            % hObject    handle to edit7 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit7 as text
            %        str2double(get(hObject,'String')) returns contents of edit7 as a double
            if (get(app.edit7,'value')~=""&&get(app.edit8,'value')~=""&&get(app.edit12,'value')~="")
                  slider1_Callback(app, event);
            end
        end

        % Callback function
        function edit8_Callback(app, event)
            % same as edit7_Callback

        end

        % Button pushed function: pushbutton10
        function pushbutton10_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton10 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % slider2_Callback(hObject, eventdata, handles);
%             global p k
            layer=get((handles.edit4),'Value');
            sliderValue2 = get(handles.slider2,'Value');
            [AZ,EL] = view;
            axes(handles.axes1);
            
%             X_min_max=get(gca,'Xlim');
            Y_min_max=get(gca,'Ylim');
            Z_min_max=get(gca,'Zlim');
            cla reset;
            box(handles.axes1,'on');
            axis equal;
            
            Draw(layer,sliderValue2);
            
            view(AZ,EL);
            axis equal; rotate3d on;
%             xlim(handles.axes1,X_min_max);
            ylim(handles.axes1,Y_min_max);zlim(handles.axes1,Z_min_max);%保持当前坐标系范围一致
            set(gcf,'color','w');
            xlabel(handles.axes1,'x(mm)','FontAngle','italic');ylabel(handles.axes1,'y(mm)','FontAngle','italic');zlabel(handles.axes1,'z(mm)','FontAngle','italic');
            title(handles.axes1,'Origami folding simulator');
            set(gca,'FontName','Gill Sans MT','Fontsize',10);
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global n h b sita d k a 
            
            a=[];b=[];sita=[];d=[];

            % read the input parameters.
            L=str2double(get((handles.edit1),'String'));
            W=str2double(get((handles.edit2),'String'));
            n=str2double(get((handles.edit3),'String'));
            sliderValue2 = get(handles.slider2,'Value');
            layer=get((handles.edit4),'Value');      
            
            axes(handles.axes1);
            cla reset;
            box(handles.axes1,'on');
            axis equal;
            view(90,0);
%             set(handles.pushbutton8,'visible','off');
            
            %% Rectangle
            if (n==4)
            %	Calculate the basic parameters
                for K=1:1:1000
                    if ((L/W*(K+1))==fix(L/W*(K+1)))
                        break;
                    end
                end
            %     K=K+2;
                M=L/W*(K+1)-1;
                h=L/(M+1)/sqrt(2);
                for i=1:1:2*M+1
                    sita0(i)=45;
                end
                for i=2*M+2:1:2*M+1+2*K+1
                    sita0(i)=135;
                end
                sita=[sita0,sita0,45];      %add one facet by add 45 in the last
                k=length(sita)-1;
                Parameters();
            %     H=h*sind(45);             % Passive thickness
            
            %   Draw the whole facets to build the origami
                Draw(layer,sliderValue2);
            end
            %% Triangle
            if (n==3)
            %	Calculate the basic parameters
                Q=L;               %The actual Length = 2(Q+1)W/tand(60).
                for i=1:1:2*Q+1
                    sita(i)=60;
                end
                for i=2*Q+2:1:(2*Q+1)*2
                    sita(i)=120;
                end
                for i=(2*Q+1)*2+1:1:(2*Q+1)*3
                    sita(i)=60;
                end
                sita(i+1)=120;
                sita(i+2)=120; %需要配成奇数组
                h=2*W*cosd(60);
                k=length(sita)-2;
                Parameters();
            
            %	Draw the whole facets to build the origami
                Draw(layer,sliderValue2);
            end
            %% Regular polygon (n>4)
            if (n>4)
            %	Calculate the basic parameters
                rapha=90-(n-2)/n*90;
                if (2*W/L<=sind(rapha))
                    disp("ERROR #### ");
                end
                x4=atan((2*W/L-sind(rapha))/cosd(rapha))/pi*180;
                x3=x4+rapha;        %adjacent angle
                h=L*sind(x3);
                for i=1:1:n       %add one facet in the last
                    sita(2*i-1)=x3;
                    sita(2*i)=x4;
                end
                sita(2*n+1)=x3;
            %     sita(2*n+2)=x4;
                k=length(sita)-1;
                Parameters();
            
            %	Draw the whole facets to build the origami
                Draw(layer,sliderValue2);
            end
        end

        % Button pushed function: pushbutton3
        function pushbutton3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            layer=get((handles.edit4),'Value');
            D_hole=str2double(get((handles.edit4_2),'String'));
            D_line=str2double(get((handles.edit4_3),'String'));
            D_state=get((handles.state),'Value');

            Aux_data=[D_hole,D_line,0,D_state];

            global p FID FID_M FID_V
            
            axes(handles.axes1);
            cla reset;
            axis equal;
            
            P=p;
            for i=1:1:length(P)
                P(i)=0;
            end
            
            %输出图形到文件
            [filename,pathname,fileindex]=uiputfile({'*.png';'*.tif';'*.pdf';'*.dxf';'*.stl'},'save picture as');
            % 输出dxf格式
            if isempty(filename)==0 
                if fileindex == 4
    
                    [filepath,name,ext] = fileparts(strcat(pathname,filename));
                    
                    FID_M = dxf_open(strcat(name,'_mountain',ext));
                    FID_V = dxf_open(strcat(name,'_valley',ext));
                    FID = dxf_open(strcat(name,'_cut',ext));
                    FID_M = dxf_set(FID_M,'Color',[1,0,0]);
                    FID_V = dxf_set(FID_V,'Color',[0,0,1]);
                    FID = dxf_set(FID,'Color',[0,1,0]);
    
                    Draft(layer,P,[D_hole,D_line,1,D_state]);
    
                    dxf_close(FID_M);
                    dxf_close(FID_V);
                    dxf_close(FID);   

                    movefile(strcat(name,'_mountain',ext),filepath);
                    movefile(strcat(name,'_valley',ext),filepath);
                    movefile(strcat(name,'_cut',ext),filepath);

                elseif fileindex == 5
                    file=strcat(pathname,filename);
                    xyz2stl(file,layer);
                    stlshow(file);
                
                else
                    Draft(layer,P,Aux_data); 
                end
            end
            
            %axis off;
            
%             set(gcf,'color','w');
%             xlabel(app.axes1,'x (mm)');ylabel(app.axes1,'y (mm)');
%             set(gca,'FontName','Gill Sans MT','Fontsize',8);
%             title(app.axes1,'Origami pattern','Fontsize',10);
            view(90,-90);
            
            new_f_handle=figure('visible','off');
            new_axes=copyobj(handles.axes1,new_f_handle);
            set(new_axes,'units','default','position','default');
            
            % 输出非dxf格式
            if isempty(filename)==0 && fileindex ~= 4
                  file=strcat(pathname,filename);
                  switch fileindex %根据不同的选择保存为不同的类型
                  case 1
                              print(new_f_handle,'-dpng',file, '-r1200');
                  case 2
                              print(new_f_handle,'-dtif',file, '-r600');
                  case 3
                              print(new_f_handle,'-dpdf',file, '-r600');  
                  end            
            end
            delete(new_f_handle);
          
        end

        % Button pushed function: pushbutton4
        function pushbutton4_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % clear all; clc;
            global h b sita d a k
            a=[];b=[];sita=[];d=[];
            image_read();
            layer=get((handles.edit4),'Value');
            h=str2double(get((handles.edit7),'String'));
            beta1=str2double(get((handles.edit8),'String'));
            beta2=str2double(get((handles.edit12),'String'));
            sliderValue = get(handles.slider1,'Value');
            sliderValue2 = get(handles.slider2,'Value');
            
            Preprocessing(sliderValue,beta1,beta2);
            Parameters();
            
            k=length(sita)-1;
            
            axes(handles.axes1);
            cla reset;
            box(handles.axes1,'on');
            axis equal;
            
            Draw(layer,sliderValue2);
            
            view(90,0);
        end

        % Button pushed function: pushbutton7
        function pushbutton7_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global p
            % hObject    handle to pushbutton7 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            layer=get((handles.edit4),'Value');
%             D_state=get((handles.state),'Value');
%             X_min_max=get(gca,'Xlim');
            Y_min_max=get(gca,'Ylim');
            Z_min_max=get(gca,'Zlim');
            [AZ,EL] = view;
            axes(handles.axes1);
            cla reset;
            box(handles.axes1,'on');
            axis equal;
            % ylim manual;xlim manual;zlim manual;
            
            Draft(layer,p, [0,0,0,0]);
            
            box(handles.axes1,'on');
            
%             xlim(handles.axes1,X_min_max);
            ylim(handles.axes1,Y_min_max);zlim(handles.axes1,Z_min_max);
            view(AZ,EL);
        end

        % Callback function
        function pushbutton8_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton8 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            view(90,0);
        end

        % Button pushed function: pushbutton9
        function pushbutton9_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
%             [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton9 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(app.edit1, 'Value', '');
            set(app.edit2, 'Value', '');
            set(app.edit3, 'Value', '');
        end

        % Value changed function: slider1
        function slider1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %
            global h b sita d a  k
            a=[];b=[];sita=[];d=[];
            layer=get((handles.edit4),'Value');
            h=str2double(get((handles.edit7),'String'));
            beta1=str2double(get((handles.edit8),'String'));
            beta2=str2double(get((handles.edit12),'String'));
            sliderValue = get(handles.slider1,'Value');
            sliderValue2 = get(handles.slider2,'Value');

            set((handles.text32_2),'String',num2str(sliderValue));
            
            Preprocessing(sliderValue,beta1,beta2);
            Parameters();
            
            k=length(sita)-1;
            
            [AZ,EL] = view;
            axes(handles.axes1);
            cla reset;
            box(handles.axes1,'on');
            axis equal;
            
            %Draw all the facets
            Draw(layer,sliderValue2);
            
            view(AZ,EL);
        end

        % Value changed function: slider2
        function slider2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to slider2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            layer=get((handles.edit4),'Value');
            sliderValue2 = get(handles.slider2,'Value');
            set((handles.text32),'String',strcat(num2str(round(sliderValue2*100)),'%'));
            [AZ,EL] = view;
%             if (AZ==90&&EL==0)
%                 set(handles.pushbutton8,'visible','off');
%             else
%                 set(handles.pushbutton8,'visible','on');
%             end
            axes(handles.axes1);
            cla reset;
            box(handles.axes1,'on');
            axis equal;
            
            Draw(layer,sliderValue2);
            
            view(AZ,EL);
        end

        % Clicked callback: uipushtool1
        function uipushtool1_ClickedCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to uipushtool1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [filename,pathname]=uiputfile({'*.jpg','JPEG(*.jpg)';...
                                                         '*.png','PNG(*.png)';...
                                                         '*.tif','TIF(*.tif)';...
                                                         '*.*',  'All Files (*.*)'},...
                                                         'Save Picture','Untitled');
            
            if filename == 0
                  return;
            else
                pathfile=[pathname filename];
                output_figure=getframe(handles.axes1);
                imwrite(output_figure.cdata,pathfile);
            end
        end

        % Clicked callback: uitoggletool4
        function uitoggletool4_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable rotation based on the
            % tool's current state.
            state = app.uitoggletool4.State;
            rotate3d(app.axes1, char(state));
        end

        % Clicked callback: uitoggletool3
        function uitoggletool3_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable pan based on the
            % tool's current state.
            state = app.uitoggletool3.State;
            pan(app.axes1, char(state));
        end

        % Clicked callback: uitoggletool2
        function uitoggletool2_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-out based on the
            % tool's current state.
            state = app.uitoggletool2.State;
            zoomModeObject = zoom(app.axes1);
            if state
                zoomModeObject.Direction = 'out';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Clicked callback: uitoggletool1
        function uitoggletool1_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-in based on the
            % tool's current state.
            state = app.uitoggletool1.State;
            zoomModeObject = zoom(app.axes1);
            if state
                zoomModeObject.Direction = 'in';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Callback function
        function edit12ValueChanged(app, event)
        % same as edit7_Callback

        end

        % Button pushed function: pushbutton3_2
        function pushbutton3_2ButtonPushed(app, event)
             % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            layer=get((handles.edit4),'Value');
            D_hole=str2double(get((handles.edit4_2),'String'));
            D_line=str2double(get((handles.edit4_3),'String'));
            D_state=get((handles.state),'Value');

            Aux_data=[D_hole;D_line;0;D_state];

            global p 

            axes(handles.axes1);
            cla reset;
%             axis equal;
            
            P=p;
            for i=1:1:length(P)
                P(i)=0;
            end
            
            Draft(layer,P,Aux_data);     
           
            %axis off;

%             set(gcf,'color','w');
%             xlabel(app.axes1,'x (mm)');ylabel(app.axes1,'y (mm)');
%             set(gca,'FontName','Gill Sans MT','Fontsize',8);
%             title(app.axes1,'Origami pattern','Fontsize',10);
            view(90,-90);

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.figure1.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {558, 558};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {221, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Origami Generator 2.0';
            app.figure1.Icon = fullfile(pathToMLAPP, 'logo.png');
            app.figure1.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.figure1.Tag = 'figure1';
            app.uitoolbar1 = uitoolbar(app.figure1);
            app.uitoolbar1.Tag = 'uitoolbar1';
            app.uipushtool1 = uipushtool(app.uitoolbar1);
            app.uipushtool1.Tag = 'uipushtool1';
            app.uipushtool1.Tooltip = 'Save Fig';
            app.uipushtool1.ClickedCallback = createCallbackFcn(app, @uipushtool1_ClickedCallback, true);
            app.uipushtool1.Icon = 'uipushtool1_image.png';
            app.uitoggletool1 = uitoggletool(app.uitoolbar1);
            app.uitoggletool1.Tag = 'uitoggletool1';
            app.uitoggletool1.Tooltip = 'Zoom';
            app.uitoggletool1.ClickedCallback = createCallbackFcn(app, @uitoggletool1_ClickedCallback, true);
            app.uitoggletool1.Icon = 'uitoggletool1_image.png';
            app.uitoggletool1.Separator = 'on';
            app.uitoggletool2 = uitoggletool(app.uitoolbar1);
            app.uitoggletool2.Tag = 'uitoggletool2';
            app.uitoggletool2.Tooltip = 'Zoom';
            app.uitoggletool2.ClickedCallback = createCallbackFcn(app, @uitoggletool2_ClickedCallback, true);
            app.uitoggletool2.Icon = 'uitoggletool2_image.png';
            app.uitoggletool2.Separator = 'on';
            app.uitoggletool3 = uitoggletool(app.uitoolbar1);
            app.uitoggletool3.Tag = 'uitoggletool3';
            app.uitoggletool3.Tooltip = 'Grab';
            app.uitoggletool3.ClickedCallback = createCallbackFcn(app, @uitoggletool3_ClickedCallback, true);
            app.uitoggletool3.Icon = 'uitoggletool3_image.png';
            app.uitoggletool3.Separator = 'on';
            app.uitoggletool4 = uitoggletool(app.uitoolbar1);
            app.uitoggletool4.Tag = 'uitoggletool4';
            app.uitoggletool4.Tooltip = 'Rotate';
            app.uitoggletool4.ClickedCallback = createCallbackFcn(app, @uitoggletool4_ClickedCallback, true);
            app.uitoggletool4.Icon = 'uitoggletool4_image.png';
            app.uitoggletool4.Separator = 'on';
            app.GridLayout = uigridlayout(app.figure1);
            app.LeftPanel = uipanel(app.GridLayout);
            app.uipanel2 = uipanel(app.LeftPanel);
            app.uipanel2.Title = 'Fabrication';
            app.uipanel2.Tag = 'uipanel2';
            app.pfnameh_2 = uilabel(app.uipanel2);
            app.pfnameh_2.Tag = 'pfnameh_2';
            app.pfnameh_2.Text = 'Laser cuting and engraving';
            app.edit4_2 = uieditfield(app.uipanel2, 'text');
            app.edit4_2.Tag = 'edit4_2';
            app.edit4_2.Value = '0';
            app.pfnameh_3 = uilabel(app.uipanel2);
            app.pfnameh_3.Tag = 'pfnameh_3';
            app.pfnameh_3.Text = 'Hole-Diameter (mm):';
            app.edit4_3 = uieditfield(app.uipanel2, 'text');
            app.edit4_3.Tag = 'edit4_3';
            app.edit4_3.Value = '0';
            app.pfnameh_4 = uilabel(app.uipanel2);
            app.pfnameh_4.Tag = 'pfnameh';
            app.pfnameh_4.Text = 'Auxiliary line (mm):';
            app.ClicktoaddlockingpatternButton = uibutton(app.uipanel2, 'state');
            app.ClicktoaddlockingpatternButton.Tag = 'state';
            app.ClicktoaddlockingpatternButton.Text = 'Click to add locking pattern';
            app.uipanel4 = uipanel(app.LeftPanel);
            app.uipanel4.Title = 'Custom Shape Input';
            app.uipanel4.Tag = 'uipanel4';
            app.text20 = uilabel(app.uipanel4);
            app.text20.Interpreter = 'latex';
            app.text20.Tag = 'text20';
            app.text20.Text = '\textit{h}  (mm):';
            app.text21 = uilabel(app.uipanel4);
            app.text21.Interpreter = 'latex';
            app.text21.Tag = 'text21';
            app.text21.Text = '$β_1$ (°):';
            app.edit8 = uieditfield(app.uipanel4, 'text');
            app.edit8.ValueChangedFcn = createCallbackFcn(app, @edit7_Callback, true);
            app.edit8.Tag = 'edit8';
            app.edit8.Value = '260';
            app.text24 = uilabel(app.uipanel4);
            app.text24.Interpreter = 'latex';
            app.text24.Tag = 'text24';
            app.text24.Text = '$L_{min}$ / \textit{h}:';
            app.text29 = uilabel(app.uipanel4);
            app.text29.Interpreter = 'latex';
            app.text29.Tag = 'text29';
            app.text29.Text = '$β_2$ (°):';
            app.edit12 = uieditfield(app.uipanel4, 'text');
            app.edit12.ValueChangedFcn = createCallbackFcn(app, @edit7_Callback, true);
            app.edit12.Tag = 'edit12';
            app.edit12.Value = '130';
            app.edit7 = uieditfield(app.uipanel4, 'text');
            app.edit7.ValueChangedFcn = createCallbackFcn(app, @edit7_Callback, true);
            app.edit7.Tag = 'edit7';
            app.edit7.Value = '4';
            app.slider1 = uislider(app.uipanel4);
            app.slider1.Limits = [1.0001 10];
            app.slider1.ValueChangedFcn = createCallbackFcn(app, @slider1_Callback, true);
            app.slider1.MinorTicks = [];
            app.slider1.Tag = 'slider1';
            app.slider1.Value = 1.1;
            app.pushbutton4 = uibutton(app.uipanel4, 'push');
            app.pushbutton4.ButtonPushedFcn = createCallbackFcn(app, @pushbutton4_Callback, true);
            app.pushbutton4.Tag = 'pushbutton4';
            app.pushbutton4.Text = 'Load shape file';
            app.text32_2 = uilabel(app.uipanel4);
            app.text32_2.Tag = 'text32_2';
            app.text32_2.Text = '1.1';
            app.uipanel1 = uipanel(app.LeftPanel);
            app.uipanel1.Title = 'Regular Shape Parameter';
            app.uipanel1.Tag = 'uipanel1';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Interpreter = 'latex';
            app.text3.Tag = 'text3';
            app.text3.Text = 'Side-Width(\textit{W}):';
            app.edit3 = uieditfield(app.uipanel1, 'text');
            app.edit3.Tag = 'edit3';
            app.edit1 = uieditfield(app.uipanel1, 'text');
            app.edit1.Tag = 'edit1';
            app.edit2 = uieditfield(app.uipanel1, 'text');
            app.edit2.Tag = 'edit2';
            app.pushbutton1 = uibutton(app.uipanel1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'Calculate';
            app.pushbutton9 = uibutton(app.uipanel1, 'push');
            app.pushbutton9.ButtonPushedFcn = createCallbackFcn(app, @pushbutton9_Callback, true);
            app.pushbutton9.Tag = 'pushbutton9';
            app.pushbutton9.Text = 'Reset';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Interpreter = 'latex';
            app.text2.Tag = 'text2';
            app.text2.Text = 'Side-Length (\textit{L}):';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Interpreter = 'latex';
            app.text4.Tag = 'text4';
            app.text4.Text = 'Side-Number (\textit{n}):';
            app.pushbutton3_2 = uibutton(app.LeftPanel, 'push');
            app.pushbutton3_2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3_2ButtonPushed, true);
            app.pushbutton3_2.Tag = 'pushbutton3_2';
            app.pushbutton3_2.Text = 'Preview';
            app.pushbutton3 = uibutton(app.LeftPanel, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3_Callback, true);
            app.pushbutton3.Tag = 'pushbutton3';
            app.pushbutton3.Text = 'Save';
            app.RightPanel = uipanel(app.GridLayout);
            app.axes1 = uiaxes(app.RightPanel);
            app.axes1.Tag = 'axes1';
            app.axes1.Visible = 'off';
            app.text13 = uilabel(app.RightPanel);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Copyright @ 2021 City University of Hong Kong';
            app.text13_2 = uilabel(app.RightPanel);
            app.text13_2.Tag = 'text13';
            app.text13_2.Text = 'Last updated at June 26, 2023';
            app.Panel = uipanel(app.RightPanel);
            app.uipanel6 = uipanel(app.Panel);
            app.uipanel6.Title = '   Shape folding ratio';
            app.uipanel6.Tag = 'uipanel6';
            app.slider2 = uislider(app.uipanel6);
            app.slider2.Limits = [0 1];
            app.slider2.MajorTicks = [];
            app.slider2.MajorTickLabels = {''};
            app.slider2.ValueChangedFcn = createCallbackFcn(app, @slider2_Callback, true);
            app.slider2.MinorTicks = [];
            app.slider2.Tag = 'slider2';
            app.slider2.Value = 1;
            app.text32 = uilabel(app.uipanel6);
            app.text32.Tag = 'text32';
            app.text32.Text = '100%';
            app.LayersSpinnerLabel = uilabel(app.Panel);
            app.LayersSpinnerLabel.Text = 'Layers';
            app.LayersSpinner = uispinner(app.Panel);
            app.LayersSpinner.Limits = [1 Inf];
            app.LayersSpinner.Tag = 'edit4';
            app.LayersSpinner.Value = 1;
            app.pushbutton10 = uibutton(app.Panel, 'push');
            app.pushbutton10.ButtonPushedFcn = createCallbackFcn(app, @pushbutton10_Callback, true);
            app.pushbutton10.Tag = 'pushbutton10';
            app.pushbutton10.Text = 'Face';
            app.pushbutton7 = uibutton(app.Panel, 'push');
            app.pushbutton7.ButtonPushedFcn = createCallbackFcn(app, @pushbutton7_Callback, true);
            app.pushbutton7.Tag = 'pushbutton7';
            app.pushbutton7.Text = 'Line';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Origami_Generator_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)Origami_Generator_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end